classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        StartButton                     matlab.ui.control.Button
        SelectSensorTypeDropDownLabel   matlab.ui.control.Label
        SelectSensorTypeDropDown        matlab.ui.control.DropDown
        SelectInputPinButtonGroup       matlab.ui.container.ButtonGroup
        A0Button                        matlab.ui.control.RadioButton
        A1Button                        matlab.ui.control.RadioButton
        A2Button                        matlab.ui.control.RadioButton
        A3Button                        matlab.ui.control.RadioButton
        A4Button                        matlab.ui.control.RadioButton
        A5Button                        matlab.ui.control.RadioButton
        PinoutButton                    matlab.ui.control.Button
        OperatingVoltageEditFieldLabel  matlab.ui.control.Label
        OperatingVoltageEditField       matlab.ui.control.NumericEditField
        MinsensorreadingEditFieldLabel  matlab.ui.control.Label
        MinsensorreadingEditField       matlab.ui.control.NumericEditField
        MaxsensorreadingEditFieldLabel  matlab.ui.control.Label
        MaxsensorreadingEditField       matlab.ui.control.NumericEditField
        NamethesensorEditFieldLabel     matlab.ui.control.Label
        NamethesensorEditField          matlab.ui.control.EditField
        UnitsEditFieldLabel             matlab.ui.control.Label
        UnitsEditField                  matlab.ui.control.EditField
        TimetoberunInSecondsEditFieldLabel  matlab.ui.control.Label
        TimetoberunInSecondsEditField   matlab.ui.control.NumericEditField
        StopmodeDropDownLabel           matlab.ui.control.Label
        StopmodeDropDown                matlab.ui.control.DropDown
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        HelpTab                         matlab.ui.container.Tab
        StepsLabel                      matlab.ui.control.Label
        SelecttheinputpinfromarduinoA0A1A2A3A4orA5Label  matlab.ui.control.Label
        SelectthestopmodeWhentostoptheprocessLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SelecttheOperatingvoltageInputvoltagetothearduinoMaximum5VLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        StarttheprocessLabel            matlab.ui.control.Label
        NotesLabel                      matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ThedatacanbesavedasanExceldatasetattheendoftheprocessLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ThesensormustbelinearbecausescalingisdonelinearlyLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% Acquire and analyze data from a sensor

            f = uifigure;

            if(app.OperatingVoltageEditField.Value > 5)
                uialert(f,'The Operating voltage is higher than 5V. Plugging it directly into the arduino may cause damage. Please use a voltage divider to get a 5V output when connecting the device to the arduino','Input voltage warning');
                return;
            elseif(app.OperatingVoltageEditField.Value == 0)
                uialert(f,'The operating voltage cannot be a "Null" value','Invalid input');
                return;
            elseif(app.MinsensorreadingEditField.Value == app.MaxsensorreadingEditField.Value)
                uialert(f,'Please correctly define the operating region. The Minimum and Maximum values cannot be the same ','Invalid input');
                return;
            elseif(app.MinsensorreadingEditField.Value > app.MaxsensorreadingEditField.Value)
                uialert(f,'Please correctly define the operating region. The Minimum value cannot exceed the Maximum value','Invalid input');
                return;
            end
            
            %% Connect to Arduino
            % Use the arduino command to connect to an Arduino device.
            a = arduino;

            maxOperating = app.MaxsensorreadingEditField.Value;
            minOperating = app.MinsensorreadingEditField.Value;
            
            figure
            h = animatedline;
            ax = gca;
            ax.YGrid = 'on';
            ax.YLim = [minOperating maxOperating];
            
            stop = false;
            startTime = datetime('now');
            tic;
            while ~stop
                
                if(app.A0Button.Value)
                    input = 'A0';
                elseif(app.A1Button.Value)
                    input = 'A1';
                elseif(app.A2Button.Value)
                    input = 'A2'; 
                elseif(app.A3Button.Value)
                    input = 'A3';
                elseif(app.A4Button.Value)
                    input = 'A4'; 
                elseif(app.A5Button.Value)
                    input = 'A0';    
                end
                
                % Read current voltage value
                v = readVoltage(a,input);
                
                OldMax = app.OperatingVoltageEditField.Value;
                OldMin = 0;
                OldValue = v;
                
                NewMax = app.MaxsensorreadingEditField.Value;
                NewMin = app.MinsensorreadingEditField.Value;
                
                OldRange = (OldMax - OldMin);
                NewRange = (NewMax - NewMin); 
                NewValue = (((OldValue - OldMin) * NewRange) / OldRange) + NewMin ;
                
                threshold = app.ThresholdEditField.Value;
                
                if NewValue >= threshold
                    writeDigitalPin(a,'D5',1);
                else
                    writeDigitalPin(a,'D5',0);
                end
                
                % Calculate temperature from voltage (based on data sheet)
            
                Sense = NewValue;
              
                % Get current time
                t =  datetime('now') - startTime;
                % Add points to animation
                addpoints(h,datenum(t),Sense)
                % Update axes
                ax.XLim = datenum([t-seconds(15) t]);
                datetick('x','keeplimits')
                drawnow
                % Check stop condition
                if strcmp(app.StopmodeDropDown.Value,'Manual')
                    stop = readDigitalPin(a,'D12');
                elseif strcmp(app.StopmodeDropDown.Value,'Timed')
                    if app.TimetoberunInSecondsEditField.Value ~= 0
                        if t >= seconds(app.TimetoberunInSecondsEditField.Value)
                            stop = 1;
                        end
                    else
                        uialert(f,'The running time cannot be "Zero" seconds','Invalid Input');
                        return;
                    end    
                end
               
            end
            
            % Input sensor limits
            
            max = app.MaxsensorreadingEditField.Value;
            min = app.MinsensorreadingEditField.Value;
            
            %% Plot the recorded data
            
            [timeLogs,tempLogs] = getpoints(h);
            timeSecs = (timeLogs-timeLogs(1))*24*3600;
            figure
            plot(timeSecs,tempLogs)
            
            last = length(timeSecs);
            
            axis([0 timeSecs(last) min max]);
            xlabel('Elapsed time (sec)')
          
            if strcmp(app.SelectSensorTypeDropDown.Value , 'Temperature')
                ylabel(strcat('Temperature ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Infrared')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Ultrasonic')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Other')
                ylabel(strcat(app.NamethesensorEditField.Value,'(',app.UnitsEditField.Value,')'))   
                 
            end
            
            
            %% Smooth out readings with moving average filter
            
            smoothedTemp = smooth(tempLogs,25);
            tempMax = smoothedTemp + 2*9/5;
            tempMin = smoothedTemp - 2*9/5;
            
            figure
            plot(timeSecs,tempLogs, timeSecs,tempMax,'r--',timeSecs,tempMin,'r--')
            xlabel('Elapsed time (sec)')
            if strcmp(app.SelectSensorTypeDropDown.Value , 'Temperature')
                ylabel(strcat('Temperature ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Infrared')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Ultrasonic')
                ylabel(strcat('Frequency ','(',app.UnitsEditField.Value,')'))
            elseif strcmp(app.SelectSensorTypeDropDown.Value , 'Other')
                ylabel(strcat(app.NamethesensorEditField.Value,'(',app.UnitsEditField.Value,')'))    
                 
            end
            hold on 
            
            %%
            % Plot the original and the smoothed temperature signal, and illustrate the
            % uncertainty.
            
            plot(timeSecs,smoothedTemp,'r')
            axis([0 timeSecs(last) min max]);
            %% Save results to a file

            T = table(timeSecs',tempLogs','VariableNames',{'Time_sec','Reading'});
            selpath = uigetdir;
            filename = 'Sensor_Data.xlsx';
            fileWrite = strcat(selpath,filename);
            % Write table to file 
            writetable(T,fileWrite)
            % Print confirmation to command line
            fprintf('Results table with %g temperature measurements saved to file %s\n',...
                length(timeSecs),filename)
                
        end

        % Button pushed function: PinoutButton
        function PinoutButtonPushed(app, event)
            arduinoPinout = imread('arduinoPinout.jpg');
            imshow(arduinoPinout);
        end

        % Value changed function: SelectSensorTypeDropDown
        function SelectSensorTypeDropDownValueChanged(app, event)
            Value = true;
            
            sensorType = app.SelectSensorTypeDropDown.Value;
            
            if strcmp(sensorType , 'Other')
                app.NamethesensorEditField.Visible = 'on';
                app.UnitsEditField.Visible = 'on';
            elseif strcmp(sensorType , 'Infrared') 
                app.UnitsEditField.Visible = 'on';
            elseif strcmp(sensorType , 'Ultrasonic') 
                app.UnitsEditField.Visible = 'on';    
            else
                app.NamethesensorEditField.Visible = 'off'; 
                app.UnitsEditField.Visible = 'on';
            end
            
        end

        % Selection changed function: SelectInputPinButtonGroup
        function SelectInputPinButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectInputPinButtonGroup.SelectedObject;
        end

        % Callback function
        function StopButtonPushed(app, event)

        end

        % Value changed function: OperatingVoltageEditField
        function OperatingVoltageEditFieldValueChanged(app, event)
            value = app.OperatingVoltageEditField.Value;
    
        end

        % Callback function
        function StopButtonPushed2(app, event)
            Value = app.StopButton.Value;
        end

        % Value changed function: TimetoberunInSecondsEditField
        function TimetoberunInSecondsEditFieldValueChanged(app, event)
            value = app.TimetoberunInSecondsEditField.Value;
            
            if strcmp(app.TimetoberunInSecondsEditField.Value , 'Manual')
                app.TimetoberunInSecondsEditField.Visible = 'off';
            end
            
        end

        % Value changed function: StopmodeDropDown
        function StopmodeDropDownValueChanged(app, event)
            value = app.StopmodeDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.StartButton = uibutton(app.MainTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectSensorTypeDropDownLabel = uilabel(app.MainTab);
            app.SelectSensorTypeDropDownLabel.Text = 'Select Sensor Type';
            app.SelectSensorTypeDropDown = uidropdown(app.MainTab);
            app.SelectSensorTypeDropDown.Items = {'Temperature', 'Other'};
            app.SelectSensorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSensorTypeDropDownValueChanged, true);
            app.SelectSensorTypeDropDown.Value = 'Temperature';
            app.SelectInputPinButtonGroup = uibuttongroup(app.MainTab);
            app.SelectInputPinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectInputPinButtonGroupSelectionChanged, true);
            app.SelectInputPinButtonGroup.Title = 'Select Input Pin';
            app.A0Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A0Button.Text = 'A0';
            app.A0Button.Value = true;
            app.A1Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A1Button.Text = 'A1';
            app.A2Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A2Button.Text = 'A2';
            app.A3Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A3Button.Text = 'A3';
            app.A4Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A4Button.Text = 'A4';
            app.A5Button = uiradiobutton(app.SelectInputPinButtonGroup);
            app.A5Button.Text = 'A5';
            app.PinoutButton = uibutton(app.SelectInputPinButtonGroup, 'push');
            app.PinoutButton.ButtonPushedFcn = createCallbackFcn(app, @PinoutButtonPushed, true);
            app.PinoutButton.Text = 'Pinout';
            app.OperatingVoltageEditFieldLabel = uilabel(app.MainTab);
            app.OperatingVoltageEditFieldLabel.Text = 'Operating Voltage';
            app.OperatingVoltageEditField = uieditfield(app.MainTab, 'numeric');
            app.OperatingVoltageEditField.Limits = [0 Inf];
            app.OperatingVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @OperatingVoltageEditFieldValueChanged, true);
            app.MinsensorreadingEditFieldLabel = uilabel(app.MainTab);
            app.MinsensorreadingEditFieldLabel.Text = 'Min sensor reading';
            app.MinsensorreadingEditField = uieditfield(app.MainTab, 'numeric');
            app.MaxsensorreadingEditFieldLabel = uilabel(app.MainTab);
            app.MaxsensorreadingEditFieldLabel.Text = 'Max sensor reading';
            app.MaxsensorreadingEditField = uieditfield(app.MainTab, 'numeric');
            app.NamethesensorEditFieldLabel = uilabel(app.MainTab);
            app.NamethesensorEditFieldLabel.Text = 'Name the sensor';
            app.NamethesensorEditField = uieditfield(app.MainTab, 'text');
            app.UnitsEditFieldLabel = uilabel(app.MainTab);
            app.UnitsEditFieldLabel.Text = 'Units';
            app.UnitsEditField = uieditfield(app.MainTab, 'text');
            app.TimetoberunInSecondsEditFieldLabel = uilabel(app.MainTab);
            app.TimetoberunInSecondsEditFieldLabel.Text = 'Time to be run (In Seconds)';
            app.TimetoberunInSecondsEditField = uieditfield(app.MainTab, 'numeric');
            app.TimetoberunInSecondsEditField.ValueChangedFcn = createCallbackFcn(app, @TimetoberunInSecondsEditFieldValueChanged, true);
            app.StopmodeDropDownLabel = uilabel(app.MainTab);
            app.StopmodeDropDownLabel.Text = 'Stop mode';
            app.StopmodeDropDown = uidropdown(app.MainTab);
            app.StopmodeDropDown.Items = {'Manual', 'Timed'};
            app.StopmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @StopmodeDropDownValueChanged, true);
            app.StopmodeDropDown.Value = 'Manual';
            app.ThresholdEditFieldLabel = uilabel(app.MainTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MainTab, 'numeric');
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.StepsLabel = uilabel(app.HelpTab);
            app.StepsLabel.Text = 'Steps:';
            app.SelecttheinputpinfromarduinoA0A1A2A3A4orA5Label = uilabel(app.HelpTab);
            app.SelecttheinputpinfromarduinoA0A1A2A3A4orA5Label.Text = '1.Select the input pin from arduino (A0,A1,A2,A3,A4 or A5)';
            app.SelectthestopmodeWhentostoptheprocessLabel = uilabel(app.HelpTab);
            app.SelectthestopmodeWhentostoptheprocessLabel.Text = '2. Select the stop mode(When to stop the process)';
            app.Label = uilabel(app.HelpTab);
            app.Label.Text = '3. Select the sensor type and specify the Units and the name of the sensor if necessary';
            app.SelecttheOperatingvoltageInputvoltagetothearduinoMaximum5VLabel = uilabel(app.HelpTab);
            app.SelecttheOperatingvoltageInputvoltagetothearduinoMaximum5VLabel.Text = '4. Select the Operating voltage (Input voltage to the arduino- Maximum 5V) ';
            app.Label_2 = uilabel(app.HelpTab);
            app.Label_2.Text = '5. Enter the minimum and maximum values that the sensor can measure (Operating Range)';
            app.StarttheprocessLabel = uilabel(app.HelpTab);
            app.StarttheprocessLabel.Text = '6. Start the process';
            app.NotesLabel = uilabel(app.HelpTab);
            app.NotesLabel.Text = 'Notes:';
            app.Label_3 = uilabel(app.HelpTab);
            app.Label_3.Text = '1. The use of manual stop mode requires an external interrupt on PIN(D12) of the arduino';
            app.ThedatacanbesavedasanExceldatasetattheendoftheprocessLabel = uilabel(app.HelpTab);
            app.ThedatacanbesavedasanExceldatasetattheendoftheprocessLabel.Text = '2. The data can be saved as an "MS-Excel(.xlsx)" data set at the end of the process';
            app.Label_4 = uilabel(app.HelpTab);
            app.Label_4.Text = '3. Do not input more than 5V to the arduino since it might cause damage to it';
            app.ThesensormustbelinearbecausescalingisdonelinearlyLabel = uilabel(app.HelpTab);
            app.ThesensormustbelinearbecausescalingisdonelinearlyLabel.Text = '4. The sensor must be linear because scaling is done linearly';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end